classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        RecieverButton  matlab.ui.control.Button
        ChannelButton   matlab.ui.control.Button
        LoadButton      matlab.ui.control.Button
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x % input signal
        fs % sampling frequency
        t % time
        y_conv % convoluted signal
        t_conv % time for convolution
        output % output signal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            clc;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile({'*.*';'*.wav';'*.mp3';'*.mpeg';'*.opus'},...
                'File Selector');
            sound = fullfile(path, file);
            [app.x, app.fs, app.t] = transmitter(sound);
            [Xmg, Xphase, f_vec] = freq_domain(app.x, app.fs);
            
            plot(app.UIAxes, app.t, app.x);
            app.UIAxes.Title.String = "Signal in time domain";
            app.UIAxes.XLabel.String = "Time";
            app.UIAxes.YLabel.String = "Magnitude";
            plot(app.UIAxes_2,f_vec,Xmg);
            app.UIAxes_2.Title.String = "Signal Magnitude in frequency domain";
            app.UIAxes_2.XLabel.String = "Frequency";
            app.UIAxes_2.YLabel.String = "Magnitude";
            plot(app.UIAxes_3,f_vec,Xphase);
            app.UIAxes_3.Title.String = "Signal Phase in frequency domain";
            app.UIAxes_3.XLabel.String = "Frequency";
            app.UIAxes_3.YLabel.String = "Phase";
        end

        % Button pushed function: ChannelButton
        function ChannelButtonPushed(app, event)
            [app.y_conv, app.t_conv] = channel(app.x, app.t, app.fs, size(app.x, 2));
            [Ymg, Yphase, f_vec] = freq_domain(app.y_conv(1:length(app.x), :), app.fs);
            
            plot(app.UIAxes, app.t, app.y_conv(1:length(app.x), :));
            app.UIAxes.Title.String = "Signal in time domain after applying system";
            app.UIAxes.XLabel.String = "Time";
            app.UIAxes.YLabel.String = "Magnitude";
            plot(app.UIAxes_2, f_vec, Ymg);
            app.UIAxes_2.Title.String = "Signal Magnitude in frequency domain after applying system";
            app.UIAxes_2.XLabel.String = "Frequency";
            app.UIAxes_2.YLabel.String = "Magnitude";
            plot(app.UIAxes_3, f_vec, Yphase);
            app.UIAxes_3.Title.String = "Signal Phase in frequency domain after applying system";
            app.UIAxes_3.XLabel.String = "Frequency";
            app.UIAxes_3.YLabel.String = "Phase";

            Y = app.y_conv;
            sound(Y(1:length(app.x), :), app.fs);
            pause(app.t(end));
            
            N = cal_noise(Y);
            app.output = Y + N;
            sound(app.output(1:length(app.x), :),app.fs);
            audiowrite('noise.wav', app.output(1:length(app.x), :), app.fs);
            [outMg, outPhase, f_vec] = freq_domain(app.output(1:length(app.x), :), app.fs);
            plot(app.UIAxes, app.t,app.output(1:length(app.x), :));
            app.UIAxes.Title.String = "Signal after adding noise in time domain";
            app.UIAxes.XLabel.String = "Time";
            app.UIAxes.YLabel.String = "Magnitude";
            plot(app.UIAxes_2, f_vec,outMg);
            app.UIAxes_2.Title.String = "Signal Magnitude after adding noise in frequency domain";
            app.UIAxes_2.XLabel.String = "Frequency";
            app.UIAxes_2.YLabel.String = "Magnitude";
            plot(app.UIAxes_3, f_vec,outPhase);
            app.UIAxes_3.Title.String = "Signal Phase after adding noise in frequency domain";
            app.UIAxes_3.XLabel.String = "Frequency";
            app.UIAxes_3.YLabel.String = "Phase";
        end

        % Button pushed function: RecieverButton
        function RecieverButtonPushed(app, event)
            final = reciever(app.output, app.fs);
            final = final(1:length(app.x), :);
            sound(final, app.fs);
            [finalMg, finalPhase, f_vec] = freq_domain(final, app.fs);
            
            plot(app.UIAxes, app.t, final);
            app.UIAxes.Title.String = "Signal at receiver in time domain";
            app.UIAxes.XLabel.String = "Time";
            app.UIAxes.YLabel.String = "Magnitude";
            plot(app.UIAxes_2, f_vec, finalMg);
            app.UIAxes_2.Title.String = "Signal Magnitude at receiver in frequency domain";
            app.UIAxes_2.XLabel.String = "Frequency";
            app.UIAxes_2.YLabel.String = "Magnitude";
            plot(app.UIAxes_3, f_vec, finalPhase);
            app.UIAxes_3.Title.String = "Signal Phase at receiver in frequency domain";
            app.UIAxes_3.XLabel.String = "Frequency";
            app.UIAxes_3.YLabel.String = "Phase";
            audiowrite('out.wav', final, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ChannelButton = uibutton(app.UIFigure, 'push');
            app.ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelButtonPushed, true);
            app.ChannelButton.Text = 'Channel';
            app.RecieverButton = uibutton(app.UIFigure, 'push');
            app.RecieverButton.ButtonPushedFcn = createCallbackFcn(app, @RecieverButtonPushed, true);
            app.RecieverButton.Text = 'Reciever';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end